classdef CoolPiano < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TextDO2              matlab.ui.control.TextArea
        TextTI               matlab.ui.control.TextArea
        TextLA               matlab.ui.control.TextArea
        TextSO               matlab.ui.control.TextArea
        TextFA               matlab.ui.control.TextArea
        TextMI               matlab.ui.control.TextArea
        TextRE               matlab.ui.control.TextArea
        TextDO1              matlab.ui.control.TextArea
        TabGroup             matlab.ui.container.TabGroup
        recordTab            matlab.ui.container.Tab
        currentNote          matlab.ui.control.Label
        field_label          matlab.ui.control.Label
        SaveSongButton       matlab.ui.control.Button
        text_field           matlab.ui.control.EditField
        SaveButton           matlab.ui.control.Button
        ResetButton          matlab.ui.control.Button
        playTab              matlab.ui.container.Tab
        TextInstruction      matlab.ui.control.TextArea
        SpeedSlidertextLow   matlab.ui.control.Label
        SpeedSliderTextFast  matlab.ui.control.Label
        SpeedSlider          matlab.ui.control.Slider
        SpeedSliderLabel     matlab.ui.control.Label
        playsongButton       matlab.ui.control.Button
        SongsBox             matlab.ui.control.ListBox
        mysongsLabel         matlab.ui.control.Label
        song                 matlab.ui.control.Label
        ButtonGroup          matlab.ui.container.ButtonGroup
        A4_d_Button          matlab.ui.control.ToggleButton
        B4_Button            matlab.ui.control.ToggleButton
        F3_d_Button          matlab.ui.control.ToggleButton
        G3_d_Button          matlab.ui.control.ToggleButton
        F3_Button            matlab.ui.control.ToggleButton
        G3_Button            matlab.ui.control.ToggleButton
        A4_Button            matlab.ui.control.ToggleButton
        C6_Button            matlab.ui.control.ToggleButton
        A6_d_Button          matlab.ui.control.ToggleButton
        B6_Button            matlab.ui.control.ToggleButton
        C5_d_Button          matlab.ui.control.ToggleButton
        D5_d_Button          matlab.ui.control.ToggleButton
        F5_d_Button          matlab.ui.control.ToggleButton
        G5_d_Button          matlab.ui.control.ToggleButton
        C5_Button            matlab.ui.control.ToggleButton
        D5_Button            matlab.ui.control.ToggleButton
        E5_Button            matlab.ui.control.ToggleButton
        F5_Button            matlab.ui.control.ToggleButton
        G5_Button            matlab.ui.control.ToggleButton
        A6_Button            matlab.ui.control.ToggleButton
        left                 matlab.ui.control.ToggleButton
        A5_d_Button          matlab.ui.control.ToggleButton
        B5_Button            matlab.ui.control.ToggleButton
        C4_d_Button          matlab.ui.control.ToggleButton
        D4_d_Button          matlab.ui.control.ToggleButton
        F4_d_Button          matlab.ui.control.ToggleButton
        G4_d_Button          matlab.ui.control.ToggleButton
        C4_Button            matlab.ui.control.ToggleButton
        D4_Button            matlab.ui.control.ToggleButton
        E4_Button            matlab.ui.control.ToggleButton
        F4_Button            matlab.ui.control.ToggleButton
        G4_Button            matlab.ui.control.ToggleButton
        A5_Button            matlab.ui.control.ToggleButton
        right                matlab.ui.control.ToggleButton
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            Basicfreq=60000; % repidness of Transferring the values to the speaker
            t=1;
               
            originalColor = get(app.ButtonGroup.SelectedObject,'BackgroundColor'); % Get original color
            currentNote=event.NewValue.Text; %save text from button
            [octave,notenum] = octave_note(currentNote); %return octave number and note number in the octave
            set(app.currentNote,"Text",currentNote);%write note to label
            
            set(app.song,"Text",[app.song.Text currentNote ',']);%add note to sequence of notes

            %color the button
            event.NewValue.BackgroundColor = KeyColor(notenum);

            %play sound
            soundNote(octave,notenum,Basicfreq,t);

            % wait before reset button color
            pause(0.2);

            % Re-enable the button and reset its color
            set(event.NewValue,'BackgroundColor',originalColor);
            set(app.ButtonGroup.SelectedObject,"Value",0);

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             % Clear labels
             app.song.Text = '';
             app.currentNote.Text = '';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
        import matlab.io.*;
        
        
        filename = strcat(app.text_field.Value, '.xlsx'); % save as an excel file
        %This will create an songs array seperated by ','.all col start with the word "song".
        data = cat(1, 'song', split(app.song.Text, ','));
        xlswrite(filename, data); %save the written name of song as the filename
            
        %enable and disable buttons
            app.song.Text = '';
            set(app.text_field,"Value","")
            set(app.field_label,"Visible",0)
            set(app.field_label,"Visible",0)
            set(app.text_field,"Visible",0)
            set(app.SaveButton,"Enable",0)
            set(app.SaveButton,"Visible",0)
            set(app.ResetButton,"Enable",1)
            set(app.ResetButton,"Visible",1) 
            set(app.SaveSongButton,"Enable",1)
            set(app.SaveSongButton,"Visible",1)
            set(app.ButtonGroup,"Enable",1)
            set(app.right,"Value",1)
        end

        % Button pushed function: SaveSongButton
        function SaveSongButtonPushed(app, event)
        %if string of notes exist enable and disable buttons
            if (app.song.Text ~= "")
            set(app.ButtonGroup,"Enable",0)
            app.currentNote.Text = '';
            set(app.ResetButton,"Enable",0)
            set(app.ResetButton,"Visible",0) 
            set(app.SaveSongButton,"Enable",0)
            set(app.SaveSongButton,"Visible",0)
            set(app.text_field,"Enable",1)
            set(app.field_label,"Visible",1)
            set(app.text_field,"Visible",1)
            set(app.SaveButton,"Enable",1)
            set(app.SaveButton,"Visible",1)
            end
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            %by select play, initialize all excel file names into our listbox
            selectedSong = app.TabGroup.SelectedTab;
            if strcmp(selectedSong.Title, 'play')
                set(app.ButtonGroup,"Enable",0)
                files = dir('*.xlsx'); % saves it in excel file
                excel_files = {files.name};
                excel_files_without_ext = cellfun(@(x) x(1:end-5), excel_files, 'UniformOutput', false);
                app.SongsBox.Items = excel_files_without_ext;
            end
        end

        % Value changed function: SongsBox
        function SongsBoxValueChanged(app, event)
            %enable and disable buttons
            set(app.ButtonGroup,"Enable",1)
            set(app.playsongButton,"Enable",1)
        end

        % Button pushed function: playsongButton
        function playsongButtonPushed(app, event)
            % Plays the selected song by reading the suitable excel file
set(app.ButtonGroup, "Enable", 1);
Basicfreq= 60000;

% Read file
value = app.SongsBox.Value;
filename = strcat(value, '.xlsx');
Selectedsong = readtable(filename);

% Play file
i = 1;
while i <= height(Selectedsong)
    currentNote = string(Selectedsong{i, 1});
    button = findobj(app.ButtonGroup.Children, 'Text', currentNote);
    originalColor = button.BackgroundColor;
    [octave, note_num] = octave_note(currentNote);

    % Color the button
    button.BackgroundColor = KeyColor(note_num);

    % Play sound
    speed = app.SpeedSlider.Value;
    soundNote(octave, note_num, Basicfreq, speed);
    % wait before reset button color
    pause(0.2);  

    % Re-enable the button and reset its color
    button.BackgroundColor = originalColor;
    set(app.ButtonGroup.SelectedObject, "Value", 0);
    set(app.right, "Value", 1);

    % Increment the loop counter
    i = i + 1;
end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ButtonGroup.Title = 'Button Group';
            app.right = uitogglebutton(app.ButtonGroup);
            app.right.Text = 'Black';
            app.right.Value = true;
            app.A5_Button = uitogglebutton(app.ButtonGroup);
            app.A5_Button.Text = 'A5';
            app.G4_Button = uitogglebutton(app.ButtonGroup);
            app.G4_Button.Text = 'G4';
            app.F4_Button = uitogglebutton(app.ButtonGroup);
            app.F4_Button.Text = 'F4';
            app.E4_Button = uitogglebutton(app.ButtonGroup);
            app.E4_Button.Text = 'E4';
            app.D4_Button = uitogglebutton(app.ButtonGroup);
            app.D4_Button.Text = 'D4';
            app.C4_Button = uitogglebutton(app.ButtonGroup);
            app.C4_Button.Text = 'C4';
            app.G4_d_Button = uitogglebutton(app.ButtonGroup);
            app.G4_d_Button.Text = 'G#4';
            app.F4_d_Button = uitogglebutton(app.ButtonGroup);
            app.F4_d_Button.Text = 'F#4';
            app.D4_d_Button = uitogglebutton(app.ButtonGroup);
            app.D4_d_Button.Text = 'D#4';
            app.C4_d_Button = uitogglebutton(app.ButtonGroup);
            app.C4_d_Button.Text = 'C#4';
            app.B5_Button = uitogglebutton(app.ButtonGroup);
            app.B5_Button.Text = 'B5';
            app.A5_d_Button = uitogglebutton(app.ButtonGroup);
            app.A5_d_Button.Text = 'A#5';
            app.left = uitogglebutton(app.ButtonGroup);
            app.left.Enable = 'off';
            app.left.Text = 'Black';
            app.A6_Button = uitogglebutton(app.ButtonGroup);
            app.A6_Button.Text = 'A6';
            app.G5_Button = uitogglebutton(app.ButtonGroup);
            app.G5_Button.Text = 'G5';
            app.F5_Button = uitogglebutton(app.ButtonGroup);
            app.F5_Button.Text = 'F5';
            app.E5_Button = uitogglebutton(app.ButtonGroup);
            app.E5_Button.Text = 'E5';
            app.D5_Button = uitogglebutton(app.ButtonGroup);
            app.D5_Button.Text = 'D5';
            app.C5_Button = uitogglebutton(app.ButtonGroup);
            app.C5_Button.Text = 'C5';
            app.G5_d_Button = uitogglebutton(app.ButtonGroup);
            app.G5_d_Button.Text = 'G#5';
            app.F5_d_Button = uitogglebutton(app.ButtonGroup);
            app.F5_d_Button.Text = 'F#5';
            app.D5_d_Button = uitogglebutton(app.ButtonGroup);
            app.D5_d_Button.Text = 'D#5';
            app.C5_d_Button = uitogglebutton(app.ButtonGroup);
            app.C5_d_Button.Text = 'C#5';
            app.B6_Button = uitogglebutton(app.ButtonGroup);
            app.B6_Button.Text = 'B6';
            app.A6_d_Button = uitogglebutton(app.ButtonGroup);
            app.A6_d_Button.Text = 'A#6';
            app.C6_Button = uitogglebutton(app.ButtonGroup);
            app.C6_Button.Text = 'C6';
            app.A4_Button = uitogglebutton(app.ButtonGroup);
            app.A4_Button.Text = 'A4';
            app.G3_Button = uitogglebutton(app.ButtonGroup);
            app.G3_Button.Text = 'G3';
            app.F3_Button = uitogglebutton(app.ButtonGroup);
            app.F3_Button.Text = 'F3';
            app.G3_d_Button = uitogglebutton(app.ButtonGroup);
            app.G3_d_Button.Text = 'G#3';
            app.F3_d_Button = uitogglebutton(app.ButtonGroup);
            app.F3_d_Button.Text = 'F#3';
            app.B4_Button = uitogglebutton(app.ButtonGroup);
            app.B4_Button.Text = 'B4';
            app.A4_d_Button = uitogglebutton(app.ButtonGroup);
            app.A4_d_Button.Text = 'A#4';
            app.song = uilabel(app.UIFigure);
            app.song.Visible = 'off';
            app.song.Text = '';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.recordTab = uitab(app.TabGroup);
            app.recordTab.Title = 'record';
            app.ResetButton = uibutton(app.recordTab, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.SaveButton = uibutton(app.recordTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.text_field = uieditfield(app.recordTab, 'text');
            app.text_field.Enable = 'off';
            app.text_field.Visible = 'off';
            app.SaveSongButton = uibutton(app.recordTab, 'push');
            app.SaveSongButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSongButtonPushed, true);
            app.SaveSongButton.Text = 'Save song';
            app.field_label = uilabel(app.recordTab);
            app.field_label.Visible = 'off';
            app.field_label.Text = 'write your song name';
            app.currentNote = uilabel(app.recordTab);
            app.currentNote.Text = '';
            app.playTab = uitab(app.TabGroup);
            app.playTab.Title = 'play';
            app.mysongsLabel = uilabel(app.playTab);
            app.mysongsLabel.Text = 'my songs:';
            app.SongsBox = uilistbox(app.playTab);
            app.SongsBox.Items = {};
            app.SongsBox.ValueChangedFcn = createCallbackFcn(app, @SongsBoxValueChanged, true);
            app.SongsBox.Value = {};
            app.playsongButton = uibutton(app.playTab, 'push');
            app.playsongButton.ButtonPushedFcn = createCallbackFcn(app, @playsongButtonPushed, true);
            app.playsongButton.Enable = 'off';
            app.playsongButton.Text = 'Play song';
            app.SpeedSliderLabel = uilabel(app.playTab);
            app.SpeedSliderLabel.Text = 'Speed';
            app.SpeedSlider = uislider(app.playTab);
            app.SpeedSlider.Limits = [0.2 4];
            app.SpeedSlider.MajorTicks = [0.2 1 1.5 2 2.5 3 3.5 4];
            app.SpeedSlider.MinorTicks = [0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4];
            app.SpeedSlider.Value = 1;
            app.SpeedSliderTextFast = uilabel(app.playTab);
            app.SpeedSliderTextFast.Text = 'Fast';
            app.SpeedSlidertextLow = uilabel(app.playTab);
            app.SpeedSlidertextLow.Text = 'Slow';
            app.TextInstruction = uitextarea(app.playTab);
            app.TextInstruction.Editable = 'off';
            app.TextInstruction.Value = {'Pick a song to play, and press "play song"'};
            app.TextDO1 = uitextarea(app.UIFigure);
            app.TextDO1.Value = {'DO'};
            app.TextRE = uitextarea(app.UIFigure);
            app.TextRE.Value = {'RE'};
            app.TextMI = uitextarea(app.UIFigure);
            app.TextMI.Value = {'MI'};
            app.TextFA = uitextarea(app.UIFigure);
            app.TextFA.Value = {'FA'};
            app.TextSO = uitextarea(app.UIFigure);
            app.TextSO.Value = {'SO'};
            app.TextLA = uitextarea(app.UIFigure);
            app.TextLA.Value = {'LA'};
            app.TextTI = uitextarea(app.UIFigure);
            app.TextTI.Value = {'TI'};
            app.TextDO2 = uitextarea(app.UIFigure);
            app.TextDO2.Value = {'DO'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CoolPiano

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end